classdef Series_fourier < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        salid                       matlab.ui.control.EditField
        SinusoidalButton            matlab.ui.control.Button
        SquareButton                matlab.ui.control.Button
        sawtoothButton              matlab.ui.control.Button
        triangularButton            matlab.ui.control.Button
        AmplitudeLabel              matlab.ui.control.Label
        amplitude                   matlab.ui.control.NumericEditField
        FrequencyLabel              matlab.ui.control.Label
        frequency                   matlab.ui.control.NumericEditField
        Frequencycom_quantityLabel  matlab.ui.control.Label
        cantcf                      matlab.ui.control.NumericEditField
        NumberofperiodsLabel        matlab.ui.control.Label
        Slidernumperio              matlab.ui.control.Slider
        DegreesDropDownLabel        matlab.ui.control.Label
        DegreesDropDown             matlab.ui.control.DropDown
        GrapherLabel                matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SinusoidalButton
        function SinusoidalButtonPushed(app, event)
            
%------- Data in -------
A = app.amplitude.Value;     %Amplitud
f = app.frequency.Value;   %fase

phi = str2double(app.DegreesDropDown.Value); %angulo

cantper = app.Slidernumperio.Value;

T= 1/f;
t = linspace(0,cantper*T);   %tiempo
%-------PROCESS ---------


switch (phi)
    
    case 90
        phi = pi/2;
    case 180
        phi = pi;
     case 270
        phi = 3*pi/2;
        
end


sinusoide = A*sin(2*pi*f*t+phi);

%-------OUTPUT -------
app.salid.Value = "GRAPHIC SINUSOIDAL";
plot(app.UIAxes,t, sinusoide)

        end

        % Button pushed function: SquareButton
        function SquareButtonPushed(app, event)
%-------DATA IN -------

A= app.amplitude.Value;
f=app.frequency.Value;
cantper = app.Slidernumperio.Value;
cantCF=app.cantcf.Value;

%------PROCESS--------
T=1/f;
t = linspace(0,cantper*T,500);

cuadrada=0;

for n=1:2:(cantCF*2-1)
    cuadrada= cuadrada+(A/n) * sin(2 * pi * (n * f) * t);
end

%------DATA OUT--------
app.salid.Value = "GRAPHIC SQUARE";
plot(app.UIAxes,t, cuadrada)
        end

        % Button pushed function: sawtoothButton
        function sawtoothButtonPushed(app, event)
        %---------DATA IN -----------
A = app.amplitude.Value;
f = app.frequency.Value;
fase = 0; %aca no se usa la fase ya que es un sen()
cantper = app.Slidernumperio.Value;
cantCF = app.cantcf.Value;
%---------PROCCESS -----------
T = 1/f; % 1 periodo es = 1 / frecuencia
t = linspace(0,cantper*T);

sierra = 0;
for n=1:(cantCF)
    sierra = sierra + (-1)^(n+1) * ((2*A)/(n*pi)) * sin(2 * pi * n * f * t);
end

app.salid.Value = "GRAPHIC SAWTOOTHE";
plot(app.UIAxes,t, sierra)

        end

        % Button pushed function: triangularButton
        function triangularButtonPushed(app, event)
            
%---------DATA IN -----------
A = app.amplitude.Value;
f = app.frequency.Value;
cantper = app.Slidernumperio.Value;
cantCF = app.cantcf.Value;
%---------PROCCESS -----------
T = 1/f; % 1 periodo es = 1 / frecuencia
t = linspace(0,cantper*T);

triangular = 0;

for n=1:2:(cantCF*2-1)
    
    %cuadrada = cuadrada +(4*A)/(n*pi)*sin(2*n*pi*t*T);
    %cuadrada = cuadrada +(4*A/n*pi) * sin(2 * pi * n * f * t);
    triangular = triangular + (8*A/(n^2*pi^2))* sin((n*pi)/2) * sin(2 * pi * n * f * t);
    
end

%--------- OUTPUT -----------
            
app.salid.Value = "GRAPHIC TRIANGULAR";

plot(app.UIAxes,t, triangular)

        end

        % Callback function
        function CheckBoxValueChanged(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.salid = uieditfield(app.UIFigure, 'text');
            app.salid.Editable = 'off';
            app.salid.Tooltip = {''};
            app.salid.Value = 'Graphic';
            app.SinusoidalButton = uibutton(app.UIFigure, 'push');
            app.SinusoidalButton.ButtonPushedFcn = createCallbackFcn(app, @SinusoidalButtonPushed, true);
            app.SinusoidalButton.Text = 'Sinusoidal';
            app.SquareButton = uibutton(app.UIFigure, 'push');
            app.SquareButton.ButtonPushedFcn = createCallbackFcn(app, @SquareButtonPushed, true);
            app.SquareButton.Text = 'Square';
            app.sawtoothButton = uibutton(app.UIFigure, 'push');
            app.sawtoothButton.ButtonPushedFcn = createCallbackFcn(app, @sawtoothButtonPushed, true);
            app.sawtoothButton.Text = 'sawtooth';
            app.triangularButton = uibutton(app.UIFigure, 'push');
            app.triangularButton.ButtonPushedFcn = createCallbackFcn(app, @triangularButtonPushed, true);
            app.triangularButton.Text = 'triangular';
            app.AmplitudeLabel = uilabel(app.UIFigure);
            app.AmplitudeLabel.Text = 'Amplitude ';
            app.amplitude = uieditfield(app.UIFigure, 'numeric');
            app.amplitude.Limits = [1 50];
            app.amplitude.Value = 1;
            app.FrequencyLabel = uilabel(app.UIFigure);
            app.FrequencyLabel.Text = 'Frequency';
            app.frequency = uieditfield(app.UIFigure, 'numeric');
            app.frequency.Limits = [1 Inf];
            app.frequency.Value = 1;
            app.Frequencycom_quantityLabel = uilabel(app.UIFigure);
            app.Frequencycom_quantityLabel.Text = {'Frequency com_quantity'; ''};
            app.cantcf = uieditfield(app.UIFigure, 'numeric');
            app.cantcf.Value = 1000;
            app.NumberofperiodsLabel = uilabel(app.UIFigure);
            app.NumberofperiodsLabel.Text = 'Number of periods';
            app.Slidernumperio = uislider(app.UIFigure);
            app.Slidernumperio.Limits = [0 10];
            app.Slidernumperio.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.Slidernumperio.Value = 2;
            app.DegreesDropDownLabel = uilabel(app.UIFigure);
            app.DegreesDropDownLabel.Text = {'Degrees'; ''};
            app.DegreesDropDown = uidropdown(app.UIFigure);
            app.DegreesDropDown.Items = {'0', '90', '180', '270'};
            app.DegreesDropDown.Value = '0';
            app.GrapherLabel = uilabel(app.UIFigure);
            app.GrapherLabel.Text = 'Grapher';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Series_fourier

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end