classdef Simulacion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        AMmodulationEditFieldLabel     matlab.ui.control.Label
        AMmodulationEditField          matlab.ui.control.NumericEditField
        AnalogButton                   matlab.ui.control.Button
        DigitalButton                  matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        SignalselectorSwitchLabel      matlab.ui.control.Label
        SignalselectorSwitch           matlab.ui.control.Switch
        AmplitudEditField              matlab.ui.control.NumericEditField
        AmplitudeLabel                 matlab.ui.control.Label
        UIAxes3                        matlab.ui.control.UIAxes
        NumberofperiodsEditFieldLabel  matlab.ui.control.Label
        NumberofperiodsEditField       matlab.ui.control.NumericEditField
        BinarynumberEditFieldLabel     matlab.ui.control.Label
        BinarynumberEditField          matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function AnalogsignalCheckBoxValueChanged(app, event)
            
        end

        % Callback function
        function DigitalsignalCheckBoxValueChanged(app, event)
 
        end

        % Button pushed function: AnalogButton
        function AnalogButtonPushed(app, event)
        
%----- DATA IN ------

%datos analogos
Ax = app.AmplitudEditField.Value;     %amplitud
fx = 3400;  %frecuencia
cpx = app.NumberofperiodsEditField.Value; % cantidad de periodos de x

%carrier (portador)
Ac = 10;     %amplitud
fc = 1000e3;  %frecuencia
cpc = 10; % cantidad de periodos de c

%modulacion AM
m = app.AMmodulationEditField.Value; % al valor de m esta entre 0 y 1 y mayor a 0


%----- PROCCES ------

%carrier

%T = 1/f; periodo igual que cpc/fc 
tc = linspace(0,cpc/fc,500);

carrier = Ac*sin(2*pi*fc*tc);

%datos analogos

tx = linspace(0,cpx/fx,500);
xt = Ax*sin(2*pi*fx*tx);

%modulacion AM
Am = (1+ m*xt).*carrier; %xt y carrier son vectores, (.*)es una multiplicacion entre matrices punto a punto

%Fm = Ac* sin (2*pi*fc*tc+n*xt);

%----- OUTPUD ------

app.UIAxes2.Title.String = "Analog data";
app.UIAxes3.Title.String = "Signal AM";

plot (app.UIAxes,tc,carrier)
plot (app.UIAxes2,tx,xt)
plot(app.UIAxes3,tx, Am)

        end

        % Value changed function: SignalselectorSwitch
        function SignalselectorSwitchValueChanged(app, event)
            value = app.SignalselectorSwitch.Value;
            
            if strcmp(value,'Analog')
                
                app.AMmodulationEditField.Enable = 'on';
                app.AnalogButton.Enable = 'on';
                app.AMmodulationEditFieldLabel.Enable = 'on';
                
               
                app.DigitalButton.Enable = 'off';
                app.BinarynumberEditField.Enable = 'off';
                
                
            else         
                app.BinarynumberEditField.Enable = 'on';
                
                app.DigitalButton.Enable = 'on';
                
                app.AMmodulationEditField.Enable = 'off';
                app.AnalogButton.Enable = 'off';
                app.AMmodulationEditFieldLabel.Enable = 'off';
                
            end
            
        end

        % Button pushed function: DigitalButton
        function DigitalButtonPushed(app, event)
%carrier (portador)

Ac = app.AmplitudEditField.Value;     %amplitud
fc = 1000e3;  %frecuencia

%bits entrada
bits = app.BinarynumberEditField.Value;
tb = 50; % numero de columnas
%----- PROCCES ------
%carrier
tc = linspace(0,(2*length(bits))/fc,length(bits)*tb);%length(bits)*tb esto es para que tengan la misma magnitud el carrier y el bitstream
carrier = Ac*sin(2*pi*fc*tc);

%definir ceros y unos

cero = zeros(1,tb);
uno = ones(1,tb);

cadena = [];
for n=1:length(bits)
    if (bits(n)=='0')
        cadena = [cadena cero];
    else
        cadena = [cadena uno];
    end
end    

bitstream = 5*cadena; %"cadena de bits" se * por 5 ya que los bits van hasta 5v
%modulacion ask
ASK = bitstream .* carrier; %estos deben tener la misma dimension
%----- OUTPUD ------

plot (app.UIAxes,tc,carrier)
plot (app.UIAxes2,bitstream)
plot (app.UIAxes3,tc,ASK)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AMmodulationEditFieldLabel = uilabel(app.UIFigure);
            app.AMmodulationEditFieldLabel.Text = 'AM-modulation';
            app.AMmodulationEditField = uieditfield(app.UIFigure, 'numeric');
            app.AMmodulationEditField.Limits = [0.1 1];
            app.AMmodulationEditField.Value = 0.1;
            app.AnalogButton = uibutton(app.UIFigure, 'push');
            app.AnalogButton.ButtonPushedFcn = createCallbackFcn(app, @AnalogButtonPushed, true);
            app.AnalogButton.Text = 'Analog ';
            app.DigitalButton = uibutton(app.UIFigure, 'push');
            app.DigitalButton.ButtonPushedFcn = createCallbackFcn(app, @DigitalButtonPushed, true);
            app.DigitalButton.Enable = 'off';
            app.DigitalButton.Text = 'Digital';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Carrier')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Date')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.SignalselectorSwitchLabel = uilabel(app.UIFigure);
            app.SignalselectorSwitchLabel.Text = 'Signal selector';
            app.SignalselectorSwitch = uiswitch(app.UIFigure, 'slider');
            app.SignalselectorSwitch.Items = {'Analog', 'Digital'};
            app.SignalselectorSwitch.ValueChangedFcn = createCallbackFcn(app, @SignalselectorSwitchValueChanged, true);
            app.SignalselectorSwitch.Value = 'Analog';
            app.AmplitudEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmplitudEditField.Limits = [1 50];
            app.AmplitudEditField.Value = 5;
            app.AmplitudeLabel = uilabel(app.UIFigure);
            app.AmplitudeLabel.Text = 'Amplitude';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'signal')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.NumberofperiodsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofperiodsEditFieldLabel.Text = 'Number of periods';
            app.NumberofperiodsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofperiodsEditField.Limits = [0 10];
            app.NumberofperiodsEditField.Value = 2;
            app.BinarynumberEditFieldLabel = uilabel(app.UIFigure);
            app.BinarynumberEditFieldLabel.Enable = 'off';
            app.BinarynumberEditFieldLabel.Text = 'Binary number';
            app.BinarynumberEditField = uieditfield(app.UIFigure, 'text');
            app.BinarynumberEditField.Enable = 'off';
            app.BinarynumberEditField.Value = '10100110';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Simulacion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end